classdef INTER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        estado       matlab.ui.control.EditField
        mensajes     matlab.ui.control.EditField
        Switch       matlab.ui.control.Switch
        SwitchLabel  matlab.ui.control.Label
        grabLuz      matlab.ui.control.Lamp
        iniciar      matlab.ui.control.Button
        Image        matlab.ui.control.Image
        letre        matlab.ui.control.UIAxes
        sala         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: iniciar
        function iniciarButtonPushed(app, event)

%             %listaVar = load('llamadas.mat');
% 
%             %Carga imagenes
%             salaApagada = imread('inte3.png');
%             letreroApagado = imread('letrero3.png');
%             salaPrendida = imread('inte2.png');
%             letreroPrendido = imread('letrero2.png');
% 
%             imshow((salaApagada),'Parent',app.sala);
%             imshow((letreroApagado),'Parent',app.letre);
% 
%             %Mensaje de Inicio
%             app.mensajes.Value = 'BIENVENIDO';
%             pause(1.5);
%             app.mensajes.Value = '';
% 
% 
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             %while true
%              %   run('ejemplo66.m');
%                 %ejemplo66.m;
%                 listaVar = load('llamadas2.mat');
%                 % if listaVar.prendido == true;
%                 %     app.grabLuz.Color = 'Red';
%                 %     if listaVar.ruido == true
%                 %         app.estado.Value = 'Lo siento, no te escucho';
%                 %     else
%                 %         app.estado.Value = 'Grabando...';
%                 %     end
% 
%                 if listaVar.auxiB == 1
%                     imshow((letreroPrendido),'Parent',app.letre);
%                     %app.mensajes.Value = 'Letrero encendido';
%                 else
%                     imshow((letreroApagado),'Parent',app.letre);
%                    % app.mensajes.Value = 'Letrero Apagado';
%                 end
%                 if listaVar.auxiA == 1
%                     imshow((salaPrendida),'Parent',app.sala);
%                 else
%                     imshow((salaApagada),'Parent',app.sala);
%                 end
% 
%                 pause(.4);
%             %end
            % Crear y configurar componentes de la aplicación
           % app.UIFigure = uifigure;

            % Configurar el temporizador
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 5, ...
                              'TimerFcn', @(~,~)app.actualizarInterfaz());

            % Iniciar el temporizador
            start(app.Timer);
        
            aux_A= 0; 
            aux_B = 0;
            %load('ye_svm.mat');
            %load('modelo_svm.mat');
                
            while 1 
                [aux_A, aux_B] = funcionMain(aux_A, aux_B, ye_svm,modelo_svm );
                aux_A = aux_A; 
                aux_B = aux_B;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'spring');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'chaquiB.png');
            app.sala = uiaxes(app.UIFigure);
            app.sala.Visible = 'off';
            app.letre = uiaxes(app.UIFigure);
            app.letre.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'chaquiN.png');
            app.iniciar = uibutton(app.UIFigure, 'push');
            app.iniciar.ButtonPushedFcn = createCallbackFcn(app, @iniciarButtonPushed, true);
            app.iniciar.Icon = fullfile(pathToMLAPP, 'buton.png');
            app.iniciar.Text = '';
            app.grabLuz = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.mensajes = uieditfield(app.UIFigure, 'text');
            app.estado = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end