classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        mensaje            matlab.ui.control.EditField
        EditField4Label    matlab.ui.control.Label
        Lamp2              matlab.ui.control.Lamp
        Lamp2Label         matlab.ui.control.Label
        Lamp               matlab.ui.control.Lamp
        LampLabel          matlab.ui.control.Label
        llamada            matlab.ui.control.NumericEditField
        EditField3Label_2  matlab.ui.control.Label
        boton              matlab.ui.control.Button
        resultado          matlab.ui.control.NumericEditField
        EditField3Label    matlab.ui.control.Label
        entradaB           matlab.ui.control.NumericEditField
        EditField2Label    matlab.ui.control.Label
        entradaA           matlab.ui.control.NumericEditField
        EditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: boton
        function botonPushed(app, event)
            tao = app.entradaA.Value;
            diametro = app.entradaB.Value;

            %definir valores para c y j
            C = (diametro/2);
            J = ((pi/2)*(C)^4);

            %mostrar resultado
            app.resultado.Value = ((tao*J)/(C));
            
            %jala variables de otro programa
            run('variab.m');
%            datos = load('datos.mat');
            datos = load('inform.mat');
 %           equis = datos.varia;
  %          app.llamada.Value = equis;
            app.llamada.Value = datos.varia;

            %encender lampara
  %          onn = load('datosBot.mat');
   %         lampara = onn.enciende;
            lampara = datos.enciende;
   %         mensash = load('textt.mat');
    %        mesa = mensash.textoo;
             

            if lampara == true
                app.Lamp.Color = 'Green';
                app.Lamp2.Color = 'Red';
                %app.mensaje.Value = mesa;
                app.mensaje.Value = datos.textoo;
            end
            if lampara == false
                app.Lamp.Color = 'Blue';
                app.Lamp2.Color = 'Green';
                app.mensaje.Value = 'El valor es FALSO';
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.entradaA = uieditfield(app.UIFigure, 'numeric');
            app.entradaA.Value = 6;
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.entradaB = uieditfield(app.UIFigure, 'numeric');
            app.entradaB.Value = 5;
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.resultado = uieditfield(app.UIFigure, 'numeric');
            app.boton = uibutton(app.UIFigure, 'push');
            app.boton.ButtonPushedFcn = createCallbackFcn(app, @botonPushed, true);
            app.EditField3Label_2 = uilabel(app.UIFigure);
            app.EditField3Label_2.Text = 'Edit Field3';
            app.llamada = uieditfield(app.UIFigure, 'numeric');
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2Label = uilabel(app.UIFigure);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.UIFigure);
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.mensaje = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end